classdef NamedPipeParallel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ClickOnButton      matlab.ui.control.StateButton
        textTextAreaLabel  matlab.ui.control.Label
        textTextArea       matlab.ui.control.TextArea
    end

    
    methods (Access = private)
        
        function app= func1(app,data)
            
            disp(strcat('Function 1----',datestr(now)));
            app.textTextArea.Value=strcat('Function 1----',datestr(now));
            pause(5);
            disp(strcat('Function 1----',datestr(now)));
            NET.addAssembly('System.Core');
            MypipeServer  = System.IO.Pipes.NamedPipeServerStream('my_pipe', ...
                            System.IO.Pipes.PipeDirection.InOut, ...
                            System.IO.Pipes.NamedPipeServerStream.MaxAllowedServerInstances,...
                            System.IO.Pipes.PipeTransmissionMode.Byte,...
            System.IO.Pipes.PipeOptions.Asynchronous);
            disp("Waiting for client connection...");
            app.textTextArea.Value=[app.textTextArea.Value;"Waiting for client connection..."];
            pause(1);
            MypipeServer.WaitForConnection();
            disp("Client Connected");
            app.textTextArea.Value=[app.textTextArea.Value;"Client Connected"];
            disp('Reading data from Client');
            app.textTextArea.Value=[app.textTextArea.Value;"Reading data from Client"];
            pause(1);
            sr = System.IO.StreamReader(MypipeServer);
           
            line = sr.ReadLine();
            disp(string(line));
            app.textTextArea.Value=[app.textTextArea.Value;string(line)];
            pause(2);
            sw = System.IO.StreamWriter(MypipeServer);
            %sw.AutoFlush = true;
            time = datestr(now);
            disp(time);
            app.textTextArea.Value=[app.textTextArea.Value;time];
            disp("Server: Hello");
            app.textTextArea.Value=[app.textTextArea.Value;"Server: Hello"];
            sw.WriteLine("Hello");
            sw.Flush();
            app.textTextArea.Value=[app.textTextArea.Value;strcat('After Communication----',datestr(now))];
            disp(strcat('After Communication----',datestr(now)));
            
             
         end
        function app= func2(app,data)
            %disp('Function 2');
            
            app.textTextArea.Value = [app.textTextArea.Value;strcat('Function 2----',datestr(now))];
            %pause(1);
            app.textTextArea.Value = [app.textTextArea.Value;strcat('Function 2----',datestr(now))];
            
        end
       
    end
    

    methods (Access = private)

        % Value changed function: ClickOnButton
        function ClickOnButtonValueChanged(app, event)
            q = parallel.pool.DataQueue;
            r = parallel.pool.DataQueue;
         
            afterEach(q, @app.func1);
            afterEach(r, @app.func2);
            parfor i = 1:2
                if i == 1
                  %func1(app);
                  send(q,i);
                  
                else
                  %func2(app);
                   send(r,i);
                end
            end


        end

        % Value changed function: textTextArea
        function textTextAreaValueChanged(app, event)
      
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ClickOnButton = uibutton(app.UIFigure, 'state');
            app.ClickOnButton.ValueChangedFcn = createCallbackFcn(app, @ClickOnButtonValueChanged, true);
            app.ClickOnButton.Text = 'Click On!';
            app.textTextAreaLabel = uilabel(app.UIFigure);
            app.textTextAreaLabel.Text = 'text';
            app.textTextArea = uitextarea(app.UIFigure);
            app.textTextArea.ValueChangedFcn = createCallbackFcn(app, @textTextAreaValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = NamedPipeParallel

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end